classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        EntradaButtonGroup      matlab.ui.container.ButtonGroup
        SineButton              matlab.ui.control.RadioButton
        DegrauButton            matlab.ui.control.RadioButton
        ControladorButtonGroup  matlab.ui.container.ButtonGroup
        alocacaoButton          matlab.ui.control.RadioButton
        LQRButton               matlab.ui.control.RadioButton
        dEditField              matlab.ui.control.NumericEditField
        dEditFieldLabel         matlab.ui.control.Label
        RunSimulationButton     matlab.ui.control.Button
        deltaEditField          matlab.ui.control.NumericEditField
        deltaEditFieldLabel     matlab.ui.control.Label
        LEditField              matlab.ui.control.NumericEditField
        LEditFieldLabel         matlab.ui.control.Label
        REditField              matlab.ui.control.NumericEditField
        REditFieldLabel         matlab.ui.control.Label
        mvEditField             matlab.ui.control.NumericEditField
        mvEditFieldLabel        matlab.ui.control.Label
        mbEditField             matlab.ui.control.NumericEditField
        mbEditFieldLabel        matlab.ui.control.Label
        grafico_simulacao       matlab.ui.control.UIAxes
        Tab2                    matlab.ui.container.Tab
        EditField               matlab.ui.control.NumericEditField
        EditFieldLabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            cla(app.grafico_simulacao)
            g = 9.8;
            mb = app.mbEditField.Value;
            mv = app.mvEditField.Value;
            L  = app.LEditField.Value;
            d = app.dEditField.Value;
            R  = app.REditField.Value;
            delta = app.deltaEditField.Value;
            Jv =  (1/2) * mv * L^2;
      
            a23 = -(( mb * Jv *g + (mb^2 )*g * delta^2 )/((Jv + mb*delta^2)^2));
            a41 = -5*g/7;
            
            A = [ 0 1 0  0 ; 
                  0  0  a23 0; 
                    0 0 0 1; 
                  a41 0 0 0 ];

            B = [0 ;
                 1;
                 0 ;
                 0];
            
            C = [0 0 1 0];
            
            D = [0];
            sys = ss(A,B,C,D)
            [a,b] = ss2tf(A,B,C,D)
            F  = tf(a,b)
            F0 = a(5)/b(5) % funcao de transferencia com S avaliado em zero

            A_nulo = [A; 
                      C]
            coluna_nula = zeros(5,1)
            A_nulo = [A_nulo coluna_nula]
            
            B_nulo = [B; 
                 0]
            C_nulo = [C 1]
            r_nulo = [0;0;0;0;-1]
            z_nulo = C_nulo - r_nulo
            
            if app.LQRButton.Value
                Q_nulo = eye(5);
                R = 0.5;
                % equação de riccati
                P_nulo = icare(A_nulo,B_nulo,Q_nulo,R)
                K_nulo = (R^-1)*transpose(B_nulo)*P_nulo
                M_nulo = (F0^-1)*transpose(K_nulo)*(K_nulo*transpose(K_nulo))^-1
                k_seguimento_lqr = K_nulo(1:4)
                ki_seguimento_lqr = K_nulo(5)
            
            end

            if app.alocacaoButton.Value
                pb = [-0.3 -2.5 -5 -10 -11]
                kb_seguimento_alocacao = acker(A_nulo, B_nulo, pb)
                k_seguimento_lqr = kb_seguimento_alocacao(1:4)
                ki_seguimento_lqr = kb_seguimento_alocacao(5)
            end 
            
        
            modelo = 'lqr_nao_linear_simulink'
            load_system(modelo)
            in = Simulink.SimulationInput(modelo)
            in = in.setVariable('g',g)
            in = in.setVariable('mb',mb)
            in = in.setVariable('L',L)
            in = in.setVariable('mv',mv)
            in = in.setVariable('d',d)
            in = in.setVariable('R',R)
            in = in.setVariable('delta',delta)
            in = in.setVariable('Jv',Jv)
            in = in.setVariable('A',A)
            in = in.setVariable('B',B)
            in = in.setVariable('C',C)
            in = in.setVariable('D',D)
            in = in.setVariable('k_seguimento_lqr',k_seguimento_lqr)
            in = in.setVariable('ki_seguimento_lqr',ki_seguimento_lqr)

            out = sim(in)
            if app.DegrauButton.Value
                Time = out.u_output.Time ;
                y_out  = out.y_output.Data;
                u_out = out.u_output.Data;
                plot(app.grafico_simulacao, Time,y_out)
                hold(app.grafico_simulacao,'on');
                plot(app.grafico_simulacao,Time, u_out)
            end 

            if app.SineButton.Value
                Time = out.u_output_sine_wave.Time ;
                y_out  = out.y_output_sine_wave.Data;
                u_out = out.u_output_sine_wave.Data;
                plot(app.grafico_simulacao, Time,y_out)
                hold(app.grafico_simulacao,'on');
                plot(app.grafico_simulacao,Time, u_out)
            end 
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.grafico_simulacao = uiaxes(app.Tab);
            title(app.grafico_simulacao, 'Posição da bola no tempo')
            xlabel(app.grafico_simulacao, 'Tempo')
            ylabel(app.grafico_simulacao, 'Posição da bola')
            zlabel(app.grafico_simulacao, 'Z')
            app.mbEditFieldLabel = uilabel(app.Tab);
            app.mbEditFieldLabel.Text = 'mb';
            app.mbEditField = uieditfield(app.Tab, 'numeric');
            app.mbEditField.Value = 0.064;
            app.mvEditFieldLabel = uilabel(app.Tab);
            app.mvEditFieldLabel.Text = 'mv';
            app.mvEditField = uieditfield(app.Tab, 'numeric');
            app.mvEditField.Value = 0.65;
            app.REditFieldLabel = uilabel(app.Tab);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.Tab, 'numeric');
            app.REditField.Value = 0.425;
            app.LEditFieldLabel = uilabel(app.Tab);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.Tab, 'numeric');
            app.LEditField.Value = 0.425;
            app.deltaEditFieldLabel = uilabel(app.Tab);
            app.deltaEditFieldLabel.Text = 'delta';
            app.deltaEditField = uieditfield(app.Tab, 'numeric');
            app.deltaEditField.Value = 0.2;
            app.RunSimulationButton = uibutton(app.Tab, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.dEditFieldLabel = uilabel(app.Tab);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.Tab, 'numeric');
            app.dEditField.Value = 0.12;
            app.ControladorButtonGroup = uibuttongroup(app.Tab);
            app.ControladorButtonGroup.Title = 'Controlador';
            app.LQRButton = uiradiobutton(app.ControladorButtonGroup);
            app.LQRButton.Text = 'LQR';
            app.LQRButton.Value = true;
            app.alocacaoButton = uiradiobutton(app.ControladorButtonGroup);
            app.alocacaoButton.Text = 'alocacao';
            app.EntradaButtonGroup = uibuttongroup(app.Tab);
            app.EntradaButtonGroup.Title = 'Entrada';
            app.DegrauButton = uiradiobutton(app.EntradaButtonGroup);
            app.DegrauButton.Text = 'Degrau';
            app.DegrauButton.Value = true;
            app.SineButton = uiradiobutton(app.EntradaButtonGroup);
            app.SineButton.Text = 'Sine';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.EditFieldLabel = uilabel(app.Tab2);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.Tab2, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end