classdef app_ball_and_beam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        ObservadordeestadosButtonGroup  matlab.ui.container.ButtonGroup
        SemObservadorButton             matlab.ui.control.RadioButton
        ComObservadorButton             matlab.ui.control.RadioButton
        LinearizaroButtonGroup          matlab.ui.container.ButtonGroup
        NoLinearButton                  matlab.ui.control.RadioButton
        LinearButton                    matlab.ui.control.RadioButton
        StopSimulationButton            matlab.ui.control.Button
        AmplitudeEntradaSlider          matlab.ui.control.Slider
        AmplitudeEntradaSliderLabel     matlab.ui.control.Label
        TempoEditField                  matlab.ui.control.NumericEditField
        TempoEditFieldLabel             matlab.ui.control.Label
        EntradaButtonGroup              matlab.ui.container.ButtonGroup
        SineButton                      matlab.ui.control.RadioButton
        DegrauButton                    matlab.ui.control.RadioButton
        ControladorButtonGroup          matlab.ui.container.ButtonGroup
        PIDButton                       matlab.ui.control.RadioButton
        MPCButton                       matlab.ui.control.RadioButton
        alocacaoButton                  matlab.ui.control.RadioButton
        LQRButton                       matlab.ui.control.RadioButton
        dEditField                      matlab.ui.control.NumericEditField
        dEditFieldLabel                 matlab.ui.control.Label
        RunSimulationButton             matlab.ui.control.Button
        deltaEditField                  matlab.ui.control.NumericEditField
        deltaEditFieldLabel             matlab.ui.control.Label
        LEditField                      matlab.ui.control.NumericEditField
        LEditFieldLabel                 matlab.ui.control.Label
        REditField                      matlab.ui.control.NumericEditField
        REditFieldLabel                 matlab.ui.control.Label
        mvEditField                     matlab.ui.control.NumericEditField
        mvEditFieldLabel                matlab.ui.control.Label
        mbEditField                     matlab.ui.control.NumericEditField
        mbEditFieldLabel                matlab.ui.control.Label
        Tab2                            matlab.ui.container.Tab
        grafico_simulacao               matlab.ui.control.UIAxes
        grafico_acao_controle           matlab.ui.control.UIAxes
        Tab3                            matlab.ui.container.Tab
        grafico_estados1                matlab.ui.control.UIAxes
        grafico_estados2                matlab.ui.control.UIAxes
        grafico_estados3                matlab.ui.control.UIAxes
        grafico_estados4                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % sistem property
        g = 9.8;
        mb = 0;
        mv = 0;
        L = 0; 
        d = 0;
        R  = 0;
        delta = 0;
       
        % model property
        Jv =0;
        A = 0;
        B = 0;
        C = 0;
        D = 0;
        a23 = 0;
        a41= 0;
        a = 0;
        b = 0;
        F0 = 0;
        
        % simulation prperty
        tempo_simulacao = 0;
        amplitude_entrada = 0;
        bool_degrau = 0;
        bool_linear = 0;

        % mpc property 
        mpc1 = 0;
        
        % steady state matriz
        A_null = 0; 
        B_null = 0;
        C_null = 0;
        D_null = 0;
        r_null = 0;
        
        % controladores
        k_seguimento = 0 ;
        ki_seguimento = 0;
        
        %observadores 
        L_observer = 0;
    end
    
    methods (Access = private)
        
        function clean_grafics(app)
            cla(app.grafico_simulacao)
            cla(app.grafico_acao_controle)
            cla(app.grafico_estados1)
            cla(app.grafico_estados2)
            cla(app.grafico_estados3)
            cla(app.grafico_estados4)
        end

        function set_values(app)
            app.mb = app.mbEditField.Value;
            app.mv = app.mvEditField.Value;
            app.L  = app.LEditField.Value;
            app.d = app.dEditField.Value;
            app.R  = app.REditField.Value;
            app.delta = app.deltaEditField.Value;
            app.tempo_simulacao = app.TempoEditField.Value;
            app.amplitude_entrada = app.AmplitudeEntradaSlider.Value;
            app.bool_degrau = app.DegrauButton.Value;
            app.bool_linear = app.LinearButton.Value;
             
            app.mpc1 = load('mpc_session_designer_ball_and_beam_linear.mat');
            app.mpc1 = app.mpc1.MPCDesignerSession.AppData.Controllers.MPC;
        end 
        function create_system_model(app)

            app.Jv =  (1/2) * app.mv * app.L^2;
            app.a23 = -(( app.mb * app.Jv *app.g + (app.mb^2 )*app.g * app.delta^2 )/((app.Jv + app.mb*app.delta^2)^2));
            app.a41 = -5*app.g/7;

            app.A = [ 0 1 0  0; 0 0 app.a23 0; 
                      0 0 0 1; app.a41 0 0 0 ];
            app.B = [0; 1; 0; 0];  
            app.C = [0 0 1 0];
            app.D = 0;
            
            [app.a, app.b] = ss2tf(app.A, app.B, app.C, app.D);

            app.F0 = app.a(5)/app.b(5); % funcao de transferencia com S avaliado em zero

            % SS error null 
            app.A_null = [app.A; app.C];
            coluna_nula = zeros(5,1);
            app.A_null = [app.A_null coluna_nula];
            app.B_null = [app.B; 0];
            app.C_null = [app.C 1];
            app.r_null = [0;0;0;0;-1];
            
        end 

        function lqr_controller(app)
            Q_null = eye(5);
            R_lqr = 0.5;

            % equação de riccati
            P_null = icare(app.A_null,app.B_null,Q_null,R_lqr);
            K_null = (R_lqr^-1)*transpose(app.B_null)*P_null;

            app.k_seguimento = K_null(1:4);
            app.ki_seguimento = K_null(5);

        end 

        function pole_alocation_controller(app)
            pb = [-0.3 -2.5 -5 -10 -11];
            kb_seguimento_alocacao = acker(app.A_null, app.B_null, pb);
        
            app.k_seguimento  = kb_seguimento_alocacao(1:4);
            app.ki_seguimento = kb_seguimento_alocacao(5);
        end 

        function state_observer(app)
            syms  s;
            fast_pole = min(real(eig(app.A)));
            pole_f = 1.5 * fast_pole;
            system_f = (s - pole_f)^length(app.A);
            dem = sym2poly(system_f);
            tf_system = tf(1,dem);
            [num, dem] = tfdata(tf_system, 'v');
            [F,~,~,~] = tf2ss(num,dem);
            Lchp = [0; 0; 0; 1];
       
            T = lyap(F, -app.A,Lchp*app.C);
            app.L_observer = inv(T)*Lchp;

        end 
        
        function model = load_model(app)

            if (app.NoLinearButton.Value == 1) && (app.SemObservadorButton.Value == 1) &&(app.MPCButton.Value == 0) 
            model = 'model_no_linear1';
            end 

            if (app.LinearButton.Value) && (app.SemObservadorButton.Value == 1) && (app.MPCButton.Value == 0) 
            model = 'model_linear';
            end 

            if (app.LinearButton.Value) && (app.ComObservadorButton.Value == 1) && (app.MPCButton.Value == 0) 
            model = 'model_linear_observador';
            end 

            if (app.NoLinearButton.Value == 1) && (app.ComObservadorButton.Value == 1) &&(app.MPCButton.Value == 0) 
            model = 'model_no_linear_observador';
            end 

            if (app.LinearButton.Value) && (app.SemObservadorButton.Value == 1) && (app.MPCButton.Value == 1) 
            model = 'model_linear_mpc';
            end 
            

        end 

        function model_output = set_model_variables(app, model)
            
            load_system(model)
            set_param(model,'StartTime','0','StopTime', num2str(app.tempo_simulacao))
            in = Simulink.SimulationInput(model);
            in = in.setVariable('g',app.g);
            in = in.setVariable('mb',app.mb);
            in = in.setVariable('L',app.L);
            in = in.setVariable('mv',app.mv);
            in = in.setVariable('d',app.d);
            in = in.setVariable('R',app.R);
            in = in.setVariable('delta',app.delta);
            in = in.setVariable('Jv',app.Jv);
            in = in.setVariable('A',app.A);
            in = in.setVariable('B',app.B);
            in = in.setVariable('C',app.C);
            in = in.setVariable('D',app.D);
            in = in.setVariable('k_seguimento',app.k_seguimento);
            in = in.setVariable('ki_seguimento',app.ki_seguimento);
            in = in.setVariable('amplitude_entrada', app.amplitude_entrada);
            in = in.setVariable('L_observer', app.L_observer);
            in = in.setVariable('bool_degrau', app.bool_degrau);
            in = in.setVariable('bool_linear',app.bool_linear);
            in = in.setVariable('mpc1', app.mpc1);
            model_output = sim(in);

        end 

        function plot_grafics(app, model_output)

            Time = model_output.u_output.Time; 
            y_out  = model_output.y_output.Data;
            u_out = model_output.u_output.Data;
            acao_controle = model_output.acao_controle.Data;
            Time_acao_controle = model_output.acao_controle.Time;

            estados1 = model_output.output_estados_1.Data;
            estados2 = model_output.output_estados_2.Data;
            estados3 = model_output.output_estados_3.Data;
            estados4 = model_output.output_estados_4.Data;
            
            plot(app.grafico_simulacao, Time, y_out)
            hold(app.grafico_simulacao,'on');
            plot(app.grafico_simulacao,Time, u_out) 
            plot(app.grafico_acao_controle, Time_acao_controle, acao_controle)


            plot(app.grafico_estados1,Time,estados1)
            plot(app.grafico_estados2,Time,estados2)
            plot(app.grafico_estados3,Time,estados3)
            plot(app.grafico_estados4,Time,estados4)

            if app.SemObservadorButton.Value
                legend(app.grafico_simulacao,'Posição', 'Setpoint')
                legend(app.grafico_acao_controle,'Ação Controle')
                legend(app.grafico_estados1,'Posição Angular Viga')
                legend(app.grafico_estados2,'Velocidade Angular Viga')
                legend(app.grafico_estados3,'Posição Bola')
                legend(app.grafico_estados4,'Velocidade Bola')
            end 

            if app.ComObservadorButton.Value
                estados_estimados1 = model_output.output_estados_observador_1.Data;
                estados_estimados2 = model_output.output_estados_observador_2.Data;
                estados_estimados3 = model_output.output_estados_observador_3.Data;
                estados_estimados4 = model_output.output_estados_observador_4.Data;
                
                hold(app.grafico_estados1,'on')
                plot(app.grafico_estados1, Time, estados_estimados1)
                hold(app.grafico_estados2,'on')
                plot(app.grafico_estados2, Time, estados_estimados2)
                hold(app.grafico_estados3,'on')
                plot(app.grafico_estados3, Time, estados_estimados3)
                hold(app.grafico_estados4,'on')
                plot(app.grafico_estados4, Time, estados_estimados4)


                legend(app.grafico_estados1,'Posição Angular Viga','Posicao Estimada')
                legend(app.grafico_estados2,'Velocidade Angular Viga','Velocidade Estimada')
                legend(app.grafico_estados3,'Posição Bola', 'Posicao Estimada')
                legend(app.grafico_estados4,'Velocidade Bola','Velocidade Estimada')

            end 


        end 

   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            

            app.clean_grafics()
            app.set_values()
            app.create_system_model()
            
            if app.LQRButton.Value
                app.lqr_controller();
            end

            if app.alocacaoButton.Value
               app.pole_alocation_controller();
            end 

            if app.ComObservadorButton.Value 
               app.state_observer();
            end 

            model = load_model(app);

            model_output = app.set_model_variables(model);

            app.plot_grafics(model_output);
           
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.mbEditFieldLabel = uilabel(app.Tab);
            app.mbEditFieldLabel.Text = 'mb';
            app.mbEditField = uieditfield(app.Tab, 'numeric');
            app.mbEditField.Value = 0.064;
            app.mvEditFieldLabel = uilabel(app.Tab);
            app.mvEditFieldLabel.Text = 'mv';
            app.mvEditField = uieditfield(app.Tab, 'numeric');
            app.mvEditField.Value = 0.65;
            app.REditFieldLabel = uilabel(app.Tab);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.Tab, 'numeric');
            app.REditField.Value = 0.425;
            app.LEditFieldLabel = uilabel(app.Tab);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.Tab, 'numeric');
            app.LEditField.Value = 0.425;
            app.deltaEditFieldLabel = uilabel(app.Tab);
            app.deltaEditFieldLabel.Text = 'delta';
            app.deltaEditField = uieditfield(app.Tab, 'numeric');
            app.deltaEditField.Value = 0.2;
            app.RunSimulationButton = uibutton(app.Tab, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.dEditFieldLabel = uilabel(app.Tab);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.Tab, 'numeric');
            app.dEditField.Value = 0.12;
            app.ControladorButtonGroup = uibuttongroup(app.Tab);
            app.ControladorButtonGroup.Title = 'Controlador';
            app.LQRButton = uiradiobutton(app.ControladorButtonGroup);
            app.LQRButton.Text = 'LQR';
            app.LQRButton.Value = true;
            app.alocacaoButton = uiradiobutton(app.ControladorButtonGroup);
            app.alocacaoButton.Text = 'alocacao';
            app.MPCButton = uiradiobutton(app.ControladorButtonGroup);
            app.MPCButton.Text = 'MPC';
            app.PIDButton = uiradiobutton(app.ControladorButtonGroup);
            app.PIDButton.Text = 'PID';
            app.EntradaButtonGroup = uibuttongroup(app.Tab);
            app.EntradaButtonGroup.Title = 'Entrada';
            app.DegrauButton = uiradiobutton(app.EntradaButtonGroup);
            app.DegrauButton.Text = 'Degrau';
            app.DegrauButton.Value = true;
            app.SineButton = uiradiobutton(app.EntradaButtonGroup);
            app.SineButton.Text = 'Sine';
            app.TempoEditFieldLabel = uilabel(app.Tab);
            app.TempoEditFieldLabel.Text = 'Tempo';
            app.TempoEditField = uieditfield(app.Tab, 'numeric');
            app.TempoEditField.Value = 200;
            app.AmplitudeEntradaSliderLabel = uilabel(app.Tab);
            app.AmplitudeEntradaSliderLabel.Text = 'Amplitude Entrada';
            app.AmplitudeEntradaSlider = uislider(app.Tab);
            app.AmplitudeEntradaSlider.Limits = [-3 3];
            app.StopSimulationButton = uibutton(app.Tab, 'push');
            app.StopSimulationButton.Text = 'Stop Simulation';
            app.LinearizaroButtonGroup = uibuttongroup(app.Tab);
            app.LinearizaroButtonGroup.Title = 'Linearizarção';
            app.LinearButton = uiradiobutton(app.LinearizaroButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.NoLinearButton = uiradiobutton(app.LinearizaroButtonGroup);
            app.NoLinearButton.Text = 'Não Linear';
            app.NoLinearButton.Value = true;
            app.ObservadordeestadosButtonGroup = uibuttongroup(app.Tab);
            app.ObservadordeestadosButtonGroup.Title = 'Observador de estados';
            app.ComObservadorButton = uiradiobutton(app.ObservadordeestadosButtonGroup);
            app.ComObservadorButton.Text = 'Com Observador';
            app.SemObservadorButton = uiradiobutton(app.ObservadordeestadosButtonGroup);
            app.SemObservadorButton.Text = 'Sem Observador';
            app.SemObservadorButton.Value = true;
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.grafico_acao_controle = uiaxes(app.Tab2);
            title(app.grafico_acao_controle, 'Ação Controle no tempo')
            xlabel(app.grafico_acao_controle, 'Tempo')
            ylabel(app.grafico_acao_controle, 'Ação Controle')
            zlabel(app.grafico_acao_controle, 'Z')
            app.grafico_simulacao = uiaxes(app.Tab2);
            title(app.grafico_simulacao, 'Posição da bola no tempo')
            xlabel(app.grafico_simulacao, 'Tempo')
            ylabel(app.grafico_simulacao, 'Posição da bola')
            zlabel(app.grafico_simulacao, 'Z')
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.grafico_estados4 = uiaxes(app.Tab3);
            title(app.grafico_estados4, 'Velocidade da Bola no Tempo')
            xlabel(app.grafico_estados4, 'Tempo')
            ylabel(app.grafico_estados4, 'Velocidade da Bola ')
            zlabel(app.grafico_estados4, 'Z')
            app.grafico_estados3 = uiaxes(app.Tab3);
            title(app.grafico_estados3, 'Posição Bola no Tempo')
            xlabel(app.grafico_estados3, 'Tempo')
            ylabel(app.grafico_estados3, 'Posição da Bola')
            zlabel(app.grafico_estados3, 'Z')
            app.grafico_estados2 = uiaxes(app.Tab3);
            title(app.grafico_estados2, 'Velocidade Angula da Viga no Tempo')
            xlabel(app.grafico_estados2, 'Tempo')
            ylabel(app.grafico_estados2, 'Velocidade Angular da viga')
            zlabel(app.grafico_estados2, 'Z')
            app.grafico_estados1 = uiaxes(app.Tab3);
            title(app.grafico_estados1, 'Angulo da Viga no Tempo')
            xlabel(app.grafico_estados1, 'Tempo')
            ylabel(app.grafico_estados1, 'Angulo da Viga')
            zlabel(app.grafico_estados1, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_ball_and_beam

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end